classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        GridLayout                 matlab.ui.container.GridLayout
        LeftPanel                  matlab.ui.container.Panel
        InputcoefficientsofAxByCzDLabel  matlab.ui.control.Label
        AEditFieldLabel            matlab.ui.control.Label
        AEditField                 matlab.ui.control.NumericEditField
        BEditFieldLabel            matlab.ui.control.Label
        BEditField                 matlab.ui.control.NumericEditField
        CEditFieldLabel            matlab.ui.control.Label
        CEditField                 matlab.ui.control.NumericEditField
        DEditFieldLabel            matlab.ui.control.Label
        DEditField                 matlab.ui.control.NumericEditField
        InputcoefficientsofAx2By2Cz2DLabel  matlab.ui.control.Label
        AEditField_2Label          matlab.ui.control.Label
        AEditField_2               matlab.ui.control.NumericEditField
        BEditField_2Label          matlab.ui.control.Label
        BEditField_2               matlab.ui.control.NumericEditField
        CEditField_2Label          matlab.ui.control.Label
        CEditField_2               matlab.ui.control.NumericEditField
        DEditField_2Label          matlab.ui.control.Label
        DEditField_2               matlab.ui.control.NumericEditField
        TypeofcurveEditFieldLabel  matlab.ui.control.Label
        TypeofcurveEditField       matlab.ui.control.EditField
        CalculateButton            matlab.ui.control.Button
        RightPanel                 matlab.ui.container.Panel
        UIAxes                     matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: AEditField
        function AEditFieldValueChanged(app, event)
            
        end

        % Value changed function: BEditField
        function BEditFieldValueChanged(app, event)
           
        end

        % Value changed function: CEditField
        function CEditFieldValueChanged(app, event)
            
        end

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
            cla(app.UIAxes)
            a = app.AEditField.Value
            b = app.BEditField.Value
            c = app.CEditField.Value
            d = app.DEditField.Value
            
            a_s = app.AEditField_2.Value
            b_s = app.BEditField_2.Value
            c_s = app.CEditField_2.Value
            d_s = app.DEditField_2.Value
            
            if (c ~= 0)
                flt_s = @(x, y) -a/c .* x  - b/c .* y  + d/c
            elseif (b ~= 0)
                flt_s = @(x, z) -a/b .*x - c/b .* z + d/b
            elseif (a ~= 0)
                flt_s = @(y, z) -b/a .* y - c/a .* z + d/a
            else
                flt_s = @(x, z) d
            end
            
            if (c ~= 0)
                ss = @(x, y) sqrt(-a_s/c_s * x.^2  - b_s/c_s * y.^2  + d_s/c_s)
            elseif (b ~= 0)
                ss = @(x, z) sqrt(-a_s/b_s *x.^2 - c_s/b_s * y.^2 + d_s/b_s)
            elseif (a ~= 0)
                ss = @(y, z) sqrt(-b_s/a_s * y.^2 - c_s/a_s * y.^2 + d_s/a_s)
            else
                ss = @(x, z) d
            end
            
            flt1_s = @(x, y, z) a.*x + b.*y + c.*z + d;
            ss1 = @(x,y,z) a_s.*x.^2 + b_s.*y.^2 + c_s.*z.^2 + d_s;
            
            
            %view([150.7 20.8])
            mss = @(x,y) -ss(x,y)
            
            %fimplicit3(ss1)
            xmi = app.UIAxes.XLim(1)
            xmx = app.UIAxes.XLim(2)
            % Define the input grid (in 3D)
            [x3, y3, z3] = meshgrid(linspace(xmi,xmx));
            % Compute the implicitly defined function x^2 + y^2 + z^3 - 0.5^2 = 0
            f1 = ss1(x3,y3,z3);
            % This surface is z = 2*y - 6*x^3, which can also be expressed as
            % 2*y - 6*x^3 - z = 0.
            f2 = flt1_s(x3,y3,z3);
            % Also compute z = 2*y - 6*x^3 in the 'traditional' way.
            [x2, y2] = meshgrid(linspace(app.UIAxes.XLim(1),app.UIAxes.XLim(2)));
            z2 = -a/c .* x2  - b/c .* y2  + d/c;
            % Visualize the two surfaces.
            %patch(app.UIAxes, isosurface(x3, y3, z3, f1, 0), 'FaceColor', [0.5 1.0 0.5], 'EdgeColor', 'none');
            %patch(app.UIAxes,isosurface(x3, y3, z3, f2, 0), 'FaceColor', [1.0 0.5 0.0], 'EdgeColor', 'none');
            %view(3); camlight; axis vis3d;
            % Find the difference field.
            f3 = f1 - f2;
            % Interpolate the difference field on the explicitly defined surface.
            f3s = interp3(x3, y3, z3, f3, x2, y2, z2);
            % Find the contour where the difference (on the surface) is zero.
            C = contours(x2, y2, f3s, [0 0]);
            % Extract the x- and y-locations from the contour matrix C.
            xL = C(1, 2:end);
            yL = C(2, 2:end);
            % Interpolate on the first surface to find z-locations for the intersection
            % line.
            zL = interp2(x2, y2, z2, xL, yL);
            % Visualize the line.
            
            
            hold(app.UIAxes,'on')
            fsurf(app.UIAxes,flt_s, [xmi xmx xmi xmx])
            fsurf(app.UIAxes,ss, [xmi xmx xmi xmx])
            fsurf(app.UIAxes,mss, [xmi xmx xmi xmx])
            line(app.UIAxes,xL,yL,zL,'Color','k','LineWidth',3);
            hold(app.UIAxes, 'off')
            
            if(a_s>0 && b_s>0 && c_s>0)
                label = "ellipse";
            elseif((a_s*b_s*c_s < 0 && d_s > 0) || (a_s*b_s*c_s > 0 && d_s < 0))
                if(a/a_s == b/b_s && b/b_s == c/c_s)
                    label = "parabola";
                else
                    label = "hyperbola";
                end
            elseif((a_s*b_s*c_s < 0 && d_s < 0) || (a_s*b_s*c_s > 0 && d_s > 0))
                label = "hyperbola";
            else
                label = "hyperbola";
            end
            app.TypeofcurveEditField.Value = label;
            
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.InputcoefficientsofAxByCzDLabel = uilabel(app.LeftPanel);
            app.InputcoefficientsofAxByCzDLabel.Text = {'Input coefficients of '; 'Ax + By + Cz = D'};
            app.AEditFieldLabel = uilabel(app.LeftPanel);
            app.AEditFieldLabel.Text = 'A';
            app.AEditField = uieditfield(app.LeftPanel, 'numeric');
            app.AEditField.ValueChangedFcn = createCallbackFcn(app, @AEditFieldValueChanged, true);
            app.AEditField.Value = 1;
            app.BEditFieldLabel = uilabel(app.LeftPanel);
            app.BEditFieldLabel.Text = 'B';
            app.BEditField = uieditfield(app.LeftPanel, 'numeric');
            app.BEditField.ValueChangedFcn = createCallbackFcn(app, @BEditFieldValueChanged, true);
            app.BEditField.Value = 1;
            app.CEditFieldLabel = uilabel(app.LeftPanel);
            app.CEditFieldLabel.Text = 'C';
            app.CEditField = uieditfield(app.LeftPanel, 'numeric');
            app.CEditField.ValueChangedFcn = createCallbackFcn(app, @CEditFieldValueChanged, true);
            app.CEditField.Value = 1;
            app.DEditFieldLabel = uilabel(app.LeftPanel);
            app.DEditFieldLabel.Text = 'D';
            app.DEditField = uieditfield(app.LeftPanel, 'numeric');
            app.DEditField.Value = 1;
            app.InputcoefficientsofAx2By2Cz2DLabel = uilabel(app.LeftPanel);
            app.InputcoefficientsofAx2By2Cz2DLabel.Text = {'Input coefficients of'; 'Ax^2 + By^2 + Cz^2 = D'};
            app.AEditField_2Label = uilabel(app.LeftPanel);
            app.AEditField_2Label.Text = 'A';
            app.AEditField_2 = uieditfield(app.LeftPanel, 'numeric');
            app.AEditField_2.Value = 1;
            app.BEditField_2Label = uilabel(app.LeftPanel);
            app.BEditField_2Label.Text = 'B';
            app.BEditField_2 = uieditfield(app.LeftPanel, 'numeric');
            app.BEditField_2.Value = 1;
            app.CEditField_2Label = uilabel(app.LeftPanel);
            app.CEditField_2Label.Text = 'C';
            app.CEditField_2 = uieditfield(app.LeftPanel, 'numeric');
            app.CEditField_2.Value = 1;
            app.DEditField_2Label = uilabel(app.LeftPanel);
            app.DEditField_2Label.Text = 'D';
            app.DEditField_2 = uieditfield(app.LeftPanel, 'numeric');
            app.DEditField_2.Value = 1;
            app.TypeofcurveEditFieldLabel = uilabel(app.LeftPanel);
            app.TypeofcurveEditFieldLabel.Text = 'Type of curve';
            app.TypeofcurveEditField = uieditfield(app.LeftPanel, 'text');
            app.CalculateButton = uibutton(app.LeftPanel, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = {'Calculate'; ''};
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'Plots')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.UIAxes.XLim = [-10 10];
            app.UIAxes.YLim = [-10 10];
            app.UIAxes.ZLim = [-10 10];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end